classdef MotorFaultAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        InputTab                        matlab.ui.container.Tab
        EditField_2                     matlab.ui.control.EditField
        UploadcsvfileEditField          matlab.ui.control.EditField
        BrowseButton                    matlab.ui.control.Button
        SelectTypeofLoadtobeanalysedDropDown  matlab.ui.control.DropDown
        SelectTypeofLoadtobeanalysedDropDownLabel  matlab.ui.control.Label
        NEXTButton                      matlab.ui.control.Button
        EditField                       matlab.ui.control.EditField
        TimeDomainTab                   matlab.ui.container.Tab
        PREVIOUSButton                  matlab.ui.control.Button
        NEXTButton_2                    matlab.ui.control.Button
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        FrequencyDomainTab              matlab.ui.container.Tab
        EditField_3                     matlab.ui.control.EditField
        NEXTButton_3                    matlab.ui.control.Button
        PREVIOUSButton_2                matlab.ui.control.Button
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        PeakFinderTab                   matlab.ui.container.Tab
        EditField_5                     matlab.ui.control.EditField
        EditField_4                     matlab.ui.control.EditField
        NEXTButton_4                    matlab.ui.control.Button
        PREVIOUSButton_3                matlab.ui.control.Button
        UIAxes4                         matlab.ui.control.UIAxes
        FilteredSignalTab               matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        EditField_6                     matlab.ui.control.EditField
        NEXTButton_5                    matlab.ui.control.Button
        PREVIOUSButton_4                matlab.ui.control.Button
        UIAxes5_2                       matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        SpectrogramAnalysisTab          matlab.ui.container.Tab
        NEXTButton_6                    matlab.ui.control.Button
        EditField_7                     matlab.ui.control.EditField
        PREVIOUSButton_5                matlab.ui.control.Button
        FilteredSpectrogramAnalysisTab  matlab.ui.container.Tab
        Hyperlink                       matlab.ui.control.Hyperlink
        EditField_9                     matlab.ui.control.EditField
        UITable_2                       matlab.ui.control.Table
        EditField_8                     matlab.ui.control.EditField
        PREVIOUSButton_6                matlab.ui.control.Button
    end

    
    properties (Access = public)
        uploaded
        selected
        rawup
        norup
        rawsel
        norsel
        P1  %sel
        P2  %up
        F1  %sel
        F2  %up
        option
        FP1
        FP2
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.TimeDomainTab;
            sr = length(app.rawup);
            sr1 = length(app.norup);
            t = linspace(0, 1, sr); % Time vector
            t1 = linspace(0, 1, sr1);
            % Calculate the sampling rate based on the time vector
            sampleRate = 1 / (t(2) - t(1));
            plot(app.UIAxes, (0:length(app.rawup)-1) / sampleRate, app.rawup, 'm-');
            xlabel(app.UIAxes, 'Time (seconds)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Original Input Signal');
            sampleRate1 = 1 / (t1(2) - t1(1));
            plot(app.UIAxes_2, (0:length(app.norup)-1) / sampleRate1, app.norup, 'm-');
            xlabel(app.UIAxes_2, 'Time (seconds)');
            ylabel(app.UIAxes_2, 'Amplitude');
            title(app.UIAxes_2, 'Normalized Input Signal');

            sra = length(app.rawsel);
            srb = length(app.norsel);
            ta = linspace(0, 1, sra); % Time vector
            t1a = linspace(0, 1, srb);
            % Calculate the sampling rate based on the time vector
            sampleRatea = 1 / (ta(2) - ta(1));
            plot(app.UIAxes_3, (0:length(app.rawsel)-1) / sampleRatea, app.rawsel);
            xlabel(app.UIAxes_3, 'Time (seconds)');
            ylabel(app.UIAxes_3, 'Amplitude');
            title(app.UIAxes_3, 'Original Healthy Signal');
            sampleRate1a = 1 / (t1a(2) - t1a(1));
            plot(app.UIAxes_4, (0:length(app.norsel)-1) / sampleRate1a, app.norsel);
            xlabel(app.UIAxes_4, 'Time (seconds)');
            ylabel(app.UIAxes_4, 'Amplitude');
            title(app.UIAxes_4, 'Normalized Healthy Signal');
        end

        % Value changed function: SelectTypeofLoadtobeanalysedDropDown
        function SelectTypeofLoadtobeanalysedDropDownValueChanged(app, event)
            value = app.SelectTypeofLoadtobeanalysedDropDown.Value;
            app.option = value;
            if strcmp(value, '3.3A')
                app.selected = fileread("3.3MAHEALTHY.csv");
                v = (strsplit(app.selected));
                app.rawsel = hex2dec(v);
                app.norsel = ((app.rawsel - 127) * 20) / 255;
            elseif strcmp(value, '2.8A')
                app.selected = fileread("2.8MAHEALTHY.csv");
                v = (strsplit(app.selected));
                app.rawsel = hex2dec(v);
                app.norsel = ((app.rawsel - 127) * 20) / 255;
            end
            
        end

        % Callback function: BrowseButton, EditField_2
        function BrowseButtonPushed(app, event)
            fileName = uigetfile('*.csv', 'Select a CSV file');
            app.uploaded = fileread(fileName);
            v = (strsplit(app.uploaded));
            app.rawup = hex2dec(v);
            app.norup = ((app.rawup - 127) * 20) / 255;
            if fileName ~= 0
                % Update the text label to indicate successful upload
                app.EditField_2.Value = [fileName ' file is uploaded.'];
            else
                % If no file was selected, display a message
                app.EditField_2.Value = 'No file selected';
            end
        end

        % Button pushed function: PREVIOUSButton
        function PREVIOUSButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.InputTab;
        end

        % Button pushed function: NEXTButton_2
        function NEXTButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.FrequencyDomainTab;
            Fs = 200;
            l1 = length(app.norsel);
            y1 = fft(app.norsel);
            p2 = abs(y1);
            p1 = p2(1:l1/2+1);
            app.P1 = p1;
            f1a = Fs*(0:(l1/2))/l1;
            app.F1 = f1a;
            plot(app.UIAxes2,f1a,p1);
            xlabel(app.UIAxes2, 'Frequency (Hz)');
            ylabel(app.UIAxes2, 'Amplitude');
            title(app.UIAxes2, 'FFT of Healthy Signal');

            l1a = length(app.norup);
            y1a = fft(app.norup);
            p2a = abs(y1a);
            p1a = p2a(1:l1a/2+1);
            app.P2 = p1a;
            f1ab = Fs*(0:(l1a/2))/l1a;
            app.F2 = f1ab;
            plot(app.UIAxes3,f1ab,p1a);
            xlabel(app.UIAxes3, 'Frequency (Hz)');
            ylabel(app.UIAxes3, 'Amplitude');
            title(app.UIAxes3, 'FFT of Input Signal');
        end

        % Button pushed function: PREVIOUSButton_2
        function PREVIOUSButton_2Pushed(app, event)
            app.TabGroup.SelectedTab = app.TimeDomainTab;
        end

        % Button pushed function: NEXTButton_3
        function NEXTButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.PeakFinderTab;
            if strcmp(app.option, '3.3A')
                threshold = 0.0048* max(app.P1); % Example threshold set to half the maximum amplitude
                % Find peaks for healthy signal
                [~,locs_healthy,~,pks_healthy] = findpeaks(app.P1, 'MinPeakHeight', threshold);
                
                % Find peaks for faulty signal
                [~,locs_faulty,~,pks_faulty] = findpeaks(app.P2, 'MinPeakHeight', threshold);
                
                % Find the difference in peaks (faulty peaks not present in healthy signal)
                unique_locs_faulty = setdiff(locs_faulty, locs_healthy);
                unique_pks_faulty = pks_faulty(ismember(locs_faulty, unique_locs_faulty));
                plot(app.UIAxes4, app.F2, app.P2);
                hold(app.UIAxes4, 'on');
                plot(app.UIAxes4, app.F2(unique_locs_faulty), app.P2(unique_locs_faulty), 'ro', 'MarkerSize', 8);
                title(app.UIAxes4, 'Unique Peaks');
                hold(app.UIAxes4, 'off');
                numUniquePeaks = length(unique_locs_faulty);
                app.EditField_5.Value = ['Number of unique peaks in Input Signal: ', num2str(numUniquePeaks)];
            elseif strcmp(app.option, '2.8A')
                threshold = 0.0165* max(app.P1); % Example threshold set to half the maximum amplitude
                % Find peaks for healthy signal
                [~,locs_healthy,~,pks_healthy] = findpeaks(app.P1, 'MinPeakHeight', threshold);
                
                % Find peaks for faulty signal
                [~,locs_faulty,~,pks_faulty] = findpeaks(app.P2, 'MinPeakHeight', threshold);
                
                % Find the difference in peaks (faulty peaks not present in healthy signal)
                unique_locs_faulty = setdiff(locs_faulty, locs_healthy);
                unique_pks_faulty = pks_faulty(ismember(locs_faulty, unique_locs_faulty));
                plot(app.UIAxes4, app.F2, app.P2);
                hold(app.UIAxes4, 'on');
                plot(app.UIAxes4, app.F2(unique_locs_faulty), app.P2(unique_locs_faulty), 'ro', 'MarkerSize', 8);
                title(app.UIAxes4, 'Unique Peaks');
                hold(app.UIAxes4, 'off');
                numUniquePeaks = length(unique_locs_faulty);
                app.EditField_5.Value = ['Number of unique peaks in Input Signal: ', num2str(numUniquePeaks)];
            end
        end

        % Button pushed function: PREVIOUSButton_3
        function PREVIOUSButton_3Pushed(app, event)
            app.TabGroup.SelectedTab = app.FrequencyDomainTab;
        end

        % Button pushed function: NEXTButton_4
        function NEXTButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSignalTab;
            fpass = [44, 55]; % Passband frequencies
            fstop = [40, 60]; % Stopband frequencies
            Fs = 200;
            % Design a Butterworth bandpass filter
            [bandpass_n, bandpass_wn] = buttord(fpass / (Fs / 2), fstop / (Fs / 2), 1, 20);
            [bandpass_b, bandpass_a] = butter(bandpass_n, bandpass_wn, 'bandpass');
            
            % Apply the bandpass filter to the signals
            filtered_I1 = filter(bandpass_b, bandpass_a, app.norsel);
            filtered_i1 = filter(bandpass_b, bandpass_a, app.norup);
            
            L = length(app.norsel);
            l1 = length(app.norup);
            % Perform FFT on filtered signals
            filtered_Y = fft(filtered_I1);
            filtered_y1 = fft(filtered_i1);
            filtered_P2 = abs(filtered_Y);
            filtered_p2 = abs(filtered_y1);
            filtered_P1 = filtered_P2(1:L / 2 + 1);
            filtered_p1 = filtered_p2(1:l1 / 2 + 1);
            app.FP1 = filtered_P1;
            app.FP2 = filtered_p1;
            plot(app.UIAxes5,app.F1,app.FP1);
            xlabel(app.UIAxes5, 'Frequency (Hz)');
            ylabel(app.UIAxes5, 'Amplitude');
            title(app.UIAxes5, 'FFT of Filterted Healthy Signal');
            plot(app.UIAxes5_2,app.F2,app.FP2);
            xlabel(app.UIAxes5_2, 'Frequency (Hz)');
            ylabel(app.UIAxes5_2, 'Amplitude');
            title(app.UIAxes5_2, 'FFT of Filterted Input Signal');
            I1 = app.norsel;
            i1 = app.norup;
            noise_healthy = I1 - filtered_I1;
            noise_faulty = i1 - filtered_i1;
            
            % Create a cell array to hold the data
            data = cell(5, 2);
            
            % SNR calculation
            SNR_healthy = mean(I1.^2) / mean(noise_healthy.^2);
            data{1, 1} = round(SNR_healthy, 6);
            SNR_faulty = mean(i1.^2) / mean(noise_faulty.^2);
            data{1, 2} = round(SNR_faulty, 6);
            
            % Kurtosis calculation
            kurtosis_healthy = kurtosis(filtered_I1);
            data{2, 1} = round(kurtosis_healthy, 6);
            kurtosis_faulty = kurtosis(filtered_i1);
            data{2, 2} = round(kurtosis_faulty, 6);
            
            % Skewness calculation
            skewness_healthy = skewness(filtered_I1);
            data{3, 1} = round(skewness_healthy, 6);
            skewness_faulty = skewness(filtered_i1);
            data{3, 2} = round(skewness_faulty, 6);
            
            % Other statistical measures (mean, variance, etc.)
            mean_healthy = mean(filtered_I1);
            data{4, 1} = round(mean_healthy, 6);
            mean_faulty = mean(filtered_i1);
            data{4, 2} = round(mean_faulty, 6);
            variance_healthy = var(filtered_I1);
            data{5, 1} = round(variance_healthy, 6);
            variance_faulty = var(filtered_i1);
            data{5, 2} = round(variance_faulty, 6);
            
            % Set row and column names
            rowNames = {'SNR', 'Kurtosis', 'Skewness', 'Mean', 'Variance'};
            colNames = {'Healthy', 'Input'};
            
            % Display data in the uitable
            app.UITable.Data = data;
            app.UITable.ColumnName = colNames;
            app.UITable.RowName = rowNames;
            
            % Set alignment and column width
            app.UITable.ColumnWidth = {100, 100}; % Adjust column width as needed
            app.UITable.ColumnFormat = {'char', 'char'};


        end

        % Button pushed function: PREVIOUSButton_4
        function PREVIOUSButton_4Pushed(app, event)
            app.TabGroup.SelectedTab = app.PeakFinderTab;
        end

        % Button pushed function: NEXTButton_5
        function NEXTButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.SpectrogramAnalysisTab;
            spectro1(app.norsel,app.norup);
        end

        % Button pushed function: PREVIOUSButton_5
        function PREVIOUSButton_5Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSignalTab;
        end

        % Button pushed function: NEXTButton_6
        function NEXTButton_6Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSpectrogramAnalysisTab;
            sp3(app.norup,app.norsel);
            % Sampling frequency
            Fs = 200;
            % Design an IIR bandpass filter for the faulty signal
            fpass = [42, 47]; % Passband frequencies
            fstop = [35, 55]; % Define slightly wider stopband to ensure attenuation in unwanted frequencies
            [bandpass_n, bandpass_wn] = buttord(fpass / (Fs / 2), fstop / (Fs / 2), 1, 20);
            [bandpass_b, bandpass_a] = butter(bandpass_n, bandpass_wn, 'bandpass');
            
            % Apply the bandpass filter to the faulty signal
            filtered_data_faulty = filter(bandpass_b, bandpass_a, app.norup);
            filtered_data_healthy = filter(bandpass_b, bandpass_a, app.norsel);
            data = cell(5, 2);
            
            % SNR calculation
            snr_healthy = snr(filtered_data_healthy);
            data{1, 1} = round(snr_healthy, 6);
            snr_faulty = snr(filtered_data_faulty);
            data{1, 2} = round(snr_faulty, 6);
            
            % Kurtosis calculation
            kurtosis_healthy = kurtosis(filtered_data_healthy);
            data{2, 1} = round(kurtosis_healthy, 6);
            kurtosis_faulty = kurtosis(filtered_data_faulty);
            data{2, 2} = round(kurtosis_faulty, 6);
            
            % Skewness calculation
            skewness_healthy = skewness(filtered_data_healthy);
            data{3, 1} = round(skewness_healthy, 6);
            skewness_faulty = skewness(filtered_data_faulty);
            data{3, 2} = round(skewness_faulty, 6);
            
            % Other statistical measures (mean, variance, etc.)
            mean_healthy = mean(filtered_data_healthy);
            data{4, 1} = round(mean_healthy, 6);
            mean_faulty = mean(filtered_data_faulty);
            data{4, 2} = round(mean_faulty, 6);
            variance_healthy = var(filtered_data_healthy);
            data{5, 1} = round(variance_healthy, 6);
            variance_faulty = var(filtered_data_faulty);
            data{5, 2} = round(variance_faulty, 6);
            
            % Set row and column names
            rowNames = {'SNR', 'Kurtosis', 'Skewness', 'Mean', 'Variance'};
            colNames = {'Healthy', 'Input'};
            
            % Display data in the uitable
            app.UITable_2.Data = data;
            app.UITable_2.ColumnName = colNames;
            app.UITable_2.RowName = rowNames;
            
            % Set alignment and column width
            app.UITable_2.ColumnWidth = {100, 100}; % Adjust column width as needed
            app.UITable_2.ColumnFormat = {'char', 'char'};


        end

        % Button pushed function: PREVIOUSButton_6
        function PREVIOUSButton_6Pushed(app, event)
            app.TabGroup.SelectedTab = app.SpectrogramAnalysisTab;
        end

        % Callback function
        function NEXTButton_7Pushed(app, event)
            app.TabGroup.SelectedTab = app.Tab8;
        end

        % Callback function
        function PREVIOUSButton_7Pushed(app, event)
            app.TabGroup.SelectedTab = app.FilteredSpectrogramAnalysisTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.EditField = uieditfield(app.InputTab, 'text');
            app.EditField.Value = 'MOTOR FAULT ANALYSER';
            app.NEXTButton = uibutton(app.InputTab, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.SelectTypeofLoadtobeanalysedDropDownLabel = uilabel(app.InputTab);
            app.SelectTypeofLoadtobeanalysedDropDownLabel.Text = 'Select Type of Load to be analysed:';
            app.SelectTypeofLoadtobeanalysedDropDown = uidropdown(app.InputTab);
            app.SelectTypeofLoadtobeanalysedDropDown.Items = {'--Select Option--', '2.8A', '3.3A'};
            app.SelectTypeofLoadtobeanalysedDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectTypeofLoadtobeanalysedDropDownValueChanged, true);
            app.SelectTypeofLoadtobeanalysedDropDown.Value = '--Select Option--';
            app.BrowseButton = uibutton(app.InputTab, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse..';
            app.UploadcsvfileEditField = uieditfield(app.InputTab, 'text');
            app.UploadcsvfileEditField.Value = 'Upload .csv file';
            app.EditField_2 = uieditfield(app.InputTab, 'text');
            app.EditField_2.ValueChangingFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.TimeDomainTab = uitab(app.TabGroup);
            app.TimeDomainTab.Title = 'Time Domain';
            app.UIAxes = uiaxes(app.TimeDomainTab);
            title(app.UIAxes, 'Signal Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_2, 'Normalized Plot')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_3, 'Signal Plot')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.TimeDomainTab);
            title(app.UIAxes_4, 'Signal Plot')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.NEXTButton_2 = uibutton(app.TimeDomainTab, 'push');
            app.NEXTButton_2.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_2Pushed, true);
            app.NEXTButton_2.Text = 'NEXT';
            app.PREVIOUSButton = uibutton(app.TimeDomainTab, 'push');
            app.PREVIOUSButton.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButtonPushed, true);
            app.PREVIOUSButton.Text = 'PREVIOUS';
            app.FrequencyDomainTab = uitab(app.TabGroup);
            app.FrequencyDomainTab.Title = 'Frequency Domain ';
            app.UIAxes2 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.FrequencyDomainTab);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.PREVIOUSButton_2 = uibutton(app.FrequencyDomainTab, 'push');
            app.PREVIOUSButton_2.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButton_2Pushed, true);
            app.PREVIOUSButton_2.Text = 'PREVIOUS';
            app.NEXTButton_3 = uibutton(app.FrequencyDomainTab, 'push');
            app.NEXTButton_3.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_3Pushed, true);
            app.NEXTButton_3.Text = 'NEXT';
            app.EditField_3 = uieditfield(app.FrequencyDomainTab, 'text');
            app.EditField_3.Value = 'FFT ANALYSIS';
            app.PeakFinderTab = uitab(app.TabGroup);
            app.PeakFinderTab.Title = 'Peak Finder';
            app.UIAxes4 = uiaxes(app.PeakFinderTab);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.PREVIOUSButton_3 = uibutton(app.PeakFinderTab, 'push');
            app.PREVIOUSButton_3.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButton_3Pushed, true);
            app.PREVIOUSButton_3.Text = 'PREVIOUS';
            app.NEXTButton_4 = uibutton(app.PeakFinderTab, 'push');
            app.NEXTButton_4.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_4Pushed, true);
            app.NEXTButton_4.Text = 'NEXT';
            app.EditField_4 = uieditfield(app.PeakFinderTab, 'text');
            app.EditField_4.Value = 'PEAK FINDER';
            app.EditField_5 = uieditfield(app.PeakFinderTab, 'text');
            app.FilteredSignalTab = uitab(app.TabGroup);
            app.FilteredSignalTab.Title = 'Filtered Signal';
            app.UIAxes5 = uiaxes(app.FilteredSignalTab);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5_2 = uiaxes(app.FilteredSignalTab);
            title(app.UIAxes5_2, 'Title')
            xlabel(app.UIAxes5_2, 'X')
            ylabel(app.UIAxes5_2, 'Y')
            zlabel(app.UIAxes5_2, 'Z')
            app.PREVIOUSButton_4 = uibutton(app.FilteredSignalTab, 'push');
            app.PREVIOUSButton_4.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButton_4Pushed, true);
            app.PREVIOUSButton_4.Text = 'PREVIOUS';
            app.NEXTButton_5 = uibutton(app.FilteredSignalTab, 'push');
            app.NEXTButton_5.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_5Pushed, true);
            app.NEXTButton_5.Text = 'NEXT';
            app.EditField_6 = uieditfield(app.FilteredSignalTab, 'text');
            app.EditField_6.Value = 'FILTERED SIGNAL';
            app.UITable = uitable(app.FilteredSignalTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.SpectrogramAnalysisTab = uitab(app.TabGroup);
            app.SpectrogramAnalysisTab.Title = 'Spectrogram Analysis';
            app.PREVIOUSButton_5 = uibutton(app.SpectrogramAnalysisTab, 'push');
            app.PREVIOUSButton_5.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButton_5Pushed, true);
            app.PREVIOUSButton_5.Text = 'PREVIOUS';
            app.EditField_7 = uieditfield(app.SpectrogramAnalysisTab, 'text');
            app.EditField_7.Value = 'SPECTROGRAM ANALYSIS';
            app.NEXTButton_6 = uibutton(app.SpectrogramAnalysisTab, 'push');
            app.NEXTButton_6.ButtonPushedFcn = createCallbackFcn(app, @NEXTButton_6Pushed, true);
            app.NEXTButton_6.Text = 'NEXT';
            app.FilteredSpectrogramAnalysisTab = uitab(app.TabGroup);
            app.FilteredSpectrogramAnalysisTab.Title = 'Filtered Spectrogram Analysis';
            app.PREVIOUSButton_6 = uibutton(app.FilteredSpectrogramAnalysisTab, 'push');
            app.PREVIOUSButton_6.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButton_6Pushed, true);
            app.PREVIOUSButton_6.Text = 'PREVIOUS';
            app.EditField_8 = uieditfield(app.FilteredSpectrogramAnalysisTab, 'text');
            app.EditField_8.Value = 'SPECTROGRAM ANALYSIS';
            app.UITable_2 = uitable(app.FilteredSpectrogramAnalysisTab);
            app.UITable_2.ColumnName = '';
            app.UITable_2.RowName = {};
            app.EditField_9 = uieditfield(app.FilteredSpectrogramAnalysisTab, 'text');
            app.EditField_9.Value = 'PERFORMANCE ANALYSIS OF STFT: ';
            app.Hyperlink = uihyperlink(app.FilteredSpectrogramAnalysisTab);
            app.Hyperlink.URL = 'https://github.com/Suneelvijay/Motor-Fault-Analyser';
            app.Hyperlink.Text = '© Github Repository';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MotorFaultAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end